classdef AFM_Exosome_App < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure          matlab.ui.Figure
        UIAxes            matlab.ui.control.UIAxes
        UIAxes_2          matlab.ui.control.UIAxes
        ButtonGroup       matlab.ui.container.ButtonGroup
        Button            matlab.ui.control.RadioButton
        Button2           matlab.ui.control.RadioButton
        Button3           matlab.ui.control.RadioButton
        UIAxes_3          matlab.ui.control.UIAxes
        UIAxes_4          matlab.ui.control.UIAxes
        LoadDataPanel     matlab.ui.container.Panel
        OpenFileButton    matlab.ui.control.Button
        ZeroHeightPanel   matlab.ui.container.Panel
        ZeroHeightButton  matlab.ui.control.Button
        EditFieldLabel    matlab.ui.control.Label
        EditField         matlab.ui.control.EditField
    end

    
    properties (Access = private)
        pth = ''; % Path to image file
        fn = ''; % image file name
        I; % Image file % Description
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: OpenFileButton
        function OpenFileButtonPushed(app, event)
            [app.fn, app.pth] = uigetfile('*.*');
            % try and load the Height channel
            try
                app.I.Height = readgwychannel([app.pth, app.fn], 0);
            catch
                disp("Height channel not found")
            end
            % try and load the stiffness channel
            try
                app.I.Stiffness = readgwychannel([app.pth, app.fn], 2);
            catch
                disp("Stiffness channel not found")                
            end
            % try and load the Adhesion channel
            try
                app.I.Adhesion = readgwychannel([app.pth, app.fn], 4);
            catch
                disp("Adhesion channel not found")
            end
            
            % try and load the Deformation channel
            try
                app.I.Deformation = readgwychannel([app.pth, app.fn], 5);
            catch
                disp("Deformation channel not found")
            end
            
            % try and load the Dissapation channel
            try
                app.I.Dissipation = readgwychannel([app.pth, app.fn], 6);
            catch
                disp("Dissipation channel not found")
            end 
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            app.UIAxes_2 = uiaxes(app.UIFigure);
            title(app.UIAxes_2, 'Title')
            xlabel(app.UIAxes_2, 'X')
            ylabel(app.UIAxes_2, 'Y')
            app.ButtonGroup = uibuttongroup(app.UIFigure);
            app.ButtonGroup.Title = 'Button Group';
            app.Button = uiradiobutton(app.ButtonGroup);
            app.Button.Text = 'Button';
            app.Button.Value = true;
            app.Button2 = uiradiobutton(app.ButtonGroup);
            app.Button2.Text = 'Button2';
            app.Button3 = uiradiobutton(app.ButtonGroup);
            app.Button3.Text = 'Button3';
            app.UIAxes_3 = uiaxes(app.UIFigure);
            title(app.UIAxes_3, 'Title')
            xlabel(app.UIAxes_3, 'X')
            ylabel(app.UIAxes_3, 'Y')
            app.UIAxes_4 = uiaxes(app.UIFigure);
            title(app.UIAxes_4, 'Title')
            xlabel(app.UIAxes_4, 'X')
            ylabel(app.UIAxes_4, 'Y')
            app.LoadDataPanel = uipanel(app.UIFigure);
            app.LoadDataPanel.Title = '1) Load Data';
            app.OpenFileButton = uibutton(app.LoadDataPanel, 'push');
            app.OpenFileButton.ButtonPushedFcn = createCallbackFcn(app, @OpenFileButtonPushed, true);
            app.OpenFileButton.Text = 'Open File';
            app.EditFieldLabel = uilabel(app.LoadDataPanel);
            app.EditFieldLabel.Text = 'Edit Field';
            app.EditField = uieditfield(app.LoadDataPanel, 'text');
            app.ZeroHeightPanel = uipanel(app.UIFigure);
            app.ZeroHeightPanel.Title = '2) Zero Height';
            app.ZeroHeightButton = uibutton(app.ZeroHeightPanel, 'push');
            app.ZeroHeightButton.Text = 'Zero Height';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = AFM_Exosome_App

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end